classdef MaeSpecialAssignment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NotchLengthEditField         matlab.ui.control.NumericEditField
        NotchLengthLabel             matlab.ui.control.Label
        NotchWidthEditField          matlab.ui.control.NumericEditField
        NotchWidthLabel              matlab.ui.control.Label
        SpacingEditField             matlab.ui.control.NumericEditField
        ArmLengthLabel               matlab.ui.control.Label
        ArmWidthEditField            matlab.ui.control.NumericEditField
        ArmWidthLabel                matlab.ui.control.Label
        UIImageHorizontal            matlab.ui.control.Image
        UIImageVertical              matlab.ui.control.Image
        UIImageGeometry              matlab.ui.control.Image
        RollNo21BEC059and21BEC061SubmittedtoProfRutulPatelLabel  matlab.ui.control.Label
        MAESpecialAssignmentLabel_2  matlab.ui.control.Label
        MAESpecialAssignmentLabel    matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        editFrequency                matlab.ui.control.NumericEditField
        FrequencyGHzEditFieldLabel   matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        btnPlotParameters            matlab.ui.control.Button
        UIAxesImpedance              matlab.ui.control.UIAxes
        UIAxesS11                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnPlotParameters
        function btnPlotParametersButtonPushed(app, event)
          % Get the frequency from the input field
frequency = app.editFrequency.Value;
freqHz = frequency * 1e9;

% Frequency range around 3 GHz for S11 and impedance analysis
frequencyRange = (2.8:0.001:3.2) * 1e9;

% Retrieve custom dimensions from the edit fields
armWidth = app.ArmWidthEditField.Value;
spacing = app.SpacingEditField.Value;
notchLength = app.NotchLengthEditField.Value;
notchWidth = app.NotchWidthEditField.Value;
armLengths = [0.0880 0.0710 0.0730 0.0650];
GP_length = 660e-3;
GP_Width = 75e-3;

% Create the inverted Amos sector antenna with custom parameters
antenna = sectorInvertedAmos('ArmWidth', armWidth, 'ArmLength', armLengths, 'NotchLength', notchLength, 'NotchWidth', notchWidth, 'Spacing',spacing, 'GroundPlaneLength', GP_length, 'GroundPlaneWidth',GP_Width);

% Capture the antenna geometry as an image
geometryFig = figure('Visible', 'off');
show(antenna);
frame = getframe(geometryFig);
geometryImg = frame.cdata;  % Capture the image data
close(geometryFig);

% Display geometry image in app.UIImageGeometry
app.UIImageGeometry.ImageSource = geometryImg;

% Generate Horizontal (Azimuth) Radiation Pattern at 3 GHz with Elevation = 0°
horizontalFig = figure('Visible', 'off');
pattern(antenna, freqHz, 'Azimuth', 0:1:360, 'Elevation', 0, 'CoordinateSystem', 'polar');
title('Horizontal Radiation Pattern (Azimuth Plane) at 3 GHz');
frameHorizontal = getframe(horizontalFig);
horizontalImg = frameHorizontal.cdata;  % Store the image data
close(horizontalFig);

% Display horizontal pattern image in app.UIImageHorizontal
app.UIImageHorizontal.ImageSource = horizontalImg;

% Generate Vertical (Elevation) Radiation Pattern at 3 GHz with Azimuth = 0°
verticalFig = figure('Visible', 'off');
pattern(antenna, freqHz, 'Elevation', -180:1:180, 'Azimuth', 0, 'CoordinateSystem', 'polar');
title('Vertical Radiation Pattern (Elevation Plane) at 3 GHz');
frameVertical = getframe(verticalFig);
verticalImg = frameVertical.cdata;  % Store the image data
close(verticalFig);

% Display vertical pattern image in app.UIImageVertical
app.UIImageVertical.ImageSource = verticalImg;

% Calculate S-parameters over the frequency range
s_params = sparameters(antenna, frequencyRange);

% Plot S11 (Return Loss) over the frequency range in UIAxesS11
axes(app.UIAxesS11);
cla(app.UIAxesS11);
rfplot(s_params, 1, 1, 'Parent', app.UIAxesS11);
title(app.UIAxesS11, 'S11 (Return Loss) vs. Frequency');
xlabel(app.UIAxesS11, 'Frequency (Hz)');
ylabel(app.UIAxesS11, 'S11 (dB)');
grid(app.UIAxesS11, 'on');

% Calculate Impedance from S-parameters (using port 1)
impedance = s2z(s_params.Parameters, 100);  % Convert S-parameters to impedance
impedance = squeeze(impedance(1,1,:));     % Extract impedance for port 1

% Plot Impedance vs. Frequency in UIAxesImpedance
axes(app.UIAxesImpedance);
cla(app.UIAxesImpedance);
plot(app.UIAxesImpedance, frequencyRange / 1e9, real(impedance), '-b', 'DisplayName', 'Real Part');  % Real part
hold(app.UIAxesImpedance, 'on');
plot(app.UIAxesImpedance, frequencyRange / 1e9, imag(impedance), '--r', 'DisplayName', 'Imaginary Part');  % Imaginary part
hold(app.UIAxesImpedance, 'off');
title(app.UIAxesImpedance, 'Input Impedance vs. Frequency');
xlabel(app.UIAxesImpedance, 'Frequency (GHz)');
ylabel(app.UIAxesImpedance, 'Impedance (Ohms)');
legend(app.UIAxesImpedance, 'show');
grid(app.UIAxesImpedance, 'on');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesS11 = uiaxes(app.UIFigure);
            title(app.UIAxesS11, 'Title')
            xlabel(app.UIAxesS11, 'X')
            ylabel(app.UIAxesS11, 'Y')
            zlabel(app.UIAxesS11, 'Z')
            app.UIAxesImpedance = uiaxes(app.UIFigure);
            title(app.UIAxesImpedance, 'Title')
            xlabel(app.UIAxesImpedance, 'X')
            ylabel(app.UIAxesImpedance, 'Y')
            zlabel(app.UIAxesImpedance, 'Z')
            app.btnPlotParameters = uibutton(app.UIFigure, 'push');
            app.btnPlotParameters.ButtonPushedFcn = createCallbackFcn(app, @btnPlotParametersButtonPushed, true);
            app.btnPlotParameters.Text = 'Plot Antenna Parameters';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Topic: Design and analysis of inverted '; 'Amos sector antenna operating at 3 GHz'};
            app.FrequencyGHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyGHzEditFieldLabel.Text = 'Frequency (GHz)';
            app.editFrequency = uieditfield(app.UIFigure, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'nirma.png');
            app.MAESpecialAssignmentLabel = uilabel(app.UIFigure);
            app.MAESpecialAssignmentLabel.Text = 'MAE Special Assignment ';
            app.MAESpecialAssignmentLabel_2 = uilabel(app.UIFigure);
            app.MAESpecialAssignmentLabel_2.Text = 'MAE Special Assignment';
            app.RollNo21BEC059and21BEC061SubmittedtoProfRutulPatelLabel = uilabel(app.UIFigure);
            app.RollNo21BEC059and21BEC061SubmittedtoProfRutulPatelLabel.Text = {'Roll No.: 21BEC059 and 21BEC061'; 'Submitted to: Prof. Rutul Patel'};
            app.UIImageGeometry = uiimage(app.UIFigure);
            app.UIImageVertical = uiimage(app.UIFigure);
            app.UIImageHorizontal = uiimage(app.UIFigure);
            app.ArmWidthLabel = uilabel(app.UIFigure);
            app.ArmWidthLabel.Text = 'ArmWidth';
            app.ArmWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.ArmLengthLabel = uilabel(app.UIFigure);
            app.ArmLengthLabel.Text = 'Spacing';
            app.SpacingEditField = uieditfield(app.UIFigure, 'numeric');
            app.NotchWidthLabel = uilabel(app.UIFigure);
            app.NotchWidthLabel.Text = 'NotchWidth';
            app.NotchWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.NotchLengthLabel = uilabel(app.UIFigure);
            app.NotchLengthLabel.Text = 'NotchLength';
            app.NotchLengthEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaeSpecialAssignment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end